classdef gelscripts_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Gelscripts_GUIUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        Sigmablur2voxel1EditFieldLabel  matlab.ui.control.Label
        Sigmablur2voxel1EditField       matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        AutomaticbinarizationthresholdLabel  matlab.ui.control.Label
        BinarizationthresholdEditFieldLabel  matlab.ui.control.Label
        BinarizationthresholdEditField  matlab.ui.control.NumericEditField
        StructuringelementsizevoxelsEditFieldLabel  matlab.ui.control.Label
        StructuringelementsizevoxelsEditField  matlab.ui.control.NumericEditField
        MinimumfractionEditFieldLabel   matlab.ui.control.Label
        MinimumfractionEditField        matlab.ui.control.NumericEditField
        RunpreprocessingButton          matlab.ui.control.Button
        SkeletonizationTab              matlab.ui.container.Tab
        MinimumterminalbranchthresholdEditFieldLabel  matlab.ui.control.Label
        MinimumterminalbranchthresholdEditField  matlab.ui.control.NumericEditField
        MinimumnodedistancethresholdEditFieldLabel  matlab.ui.control.Label
        MinimumnodedistancethresholdEditField  matlab.ui.control.NumericEditField
        RunskeletonizationButton        matlab.ui.control.Button
        EdgenodedistanceEditFieldLabel  matlab.ui.control.Label
        EdgenodedistanceEditField       matlab.ui.control.NumericEditField
        VisualizeboxedgematerialCheckBox_2  matlab.ui.control.CheckBox
        AnalysisTab                     matlab.ui.container.Tab
        TortuosityofeachdimensionseparatelyCheckBox  matlab.ui.control.CheckBox
        BinsizeEditFieldLabel           matlab.ui.control.Label
        BinsizeEditField                matlab.ui.control.NumericEditField
        MaximumbinmLabel                matlab.ui.control.Label
        MaximumbinmEditField            matlab.ui.control.NumericEditField
        RunanalysisButton_2             matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        VisualizeascubicvoxelsCheckBox  matlab.ui.control.CheckBox
        OutputtypeDropDownLabel         matlab.ui.control.Label
        OutputtypeDropDown              matlab.ui.control.DropDown
        SwellskeletonEditFieldLabel     matlab.ui.control.Label
        SwellskeletonEditField          matlab.ui.control.NumericEditField
        NodesizeEditFieldLabel          matlab.ui.control.Label
        NodesizeEditField               matlab.ui.control.NumericEditField
        RunvisualizationButton          matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        RunfullanalysisButton           matlab.ui.control.Button
        DisplayprogressCheckBox         matlab.ui.control.CheckBox
        CreateoptimizationtoolsCheckBox  matlab.ui.control.CheckBox
        SavevariablesasmatCheckBox      matlab.ui.control.CheckBox
        SaveoutputastxtCheckBox         matlab.ui.control.CheckBox
        VisualizationPanel              matlab.ui.container.Panel
        FilenameDropDownLabel           matlab.ui.control.Label
        FilenameDropDown                matlab.ui.control.DropDown
        ListBox                         matlab.ui.control.ListBox
        VisualizeButton                 matlab.ui.control.Button
        GeneralInputPanel               matlab.ui.container.TabGroup
        GeneralsettingsTab              matlab.ui.container.Tab
        ProjectfolderButton             matlab.ui.control.Button
        ExcludefilesfromprojectButton   matlab.ui.control.Button
        ShowfinetuningoptionsCheckBox   matlab.ui.control.CheckBox
        Maxzframe0allLabel              matlab.ui.control.Label
        Maxzframe0allEditField          matlab.ui.control.NumericEditField
        PixelsizeXmLabel                matlab.ui.control.Label
        PixelsizeXmEditField            matlab.ui.control.NumericEditField
        PixelsizeYmLabel                matlab.ui.control.Label
        PixelsizeYmEditField            matlab.ui.control.NumericEditField
        PixelsizeZmLabel                matlab.ui.control.Label
        PixelsizeZmEditField            matlab.ui.control.NumericEditField
        ParticlediametermLabel          matlab.ui.control.Label
        ParticlediametermEditField      matlab.ui.control.NumericEditField
        ResetdefaultvaluesButton        matlab.ui.control.Button
        CoordinatediagramTab            matlab.ui.container.Tab
        InputboxsizeyLabel              matlab.ui.control.Label
        InputboxsizeyEditField          matlab.ui.control.NumericEditField
        InputboxsizexLabel              matlab.ui.control.Label
        InputboxsizexEditField          matlab.ui.control.NumericEditField
        InputboxsizezLabel              matlab.ui.control.Label
        InputboxsizezEditField          matlab.ui.control.NumericEditField
        PointspreadfunctionmxEditFieldLabel  matlab.ui.control.Label
        PointspreadfunctionmxEditField  matlab.ui.control.NumericEditField
        PointspreadfunctionmyEditFieldLabel  matlab.ui.control.Label
        PointspreadfunctionmyEditField  matlab.ui.control.NumericEditField
        PointspreadfunctionmzEditFieldLabel  matlab.ui.control.Label
        PointspreadfunctionmzEditField  matlab.ui.control.NumericEditField
        OutputboxsizezEditFieldLabel    matlab.ui.control.Label
        OutputboxsizezEditField         matlab.ui.control.NumericEditField
        OutputboxsizeyEditFieldLabel    matlab.ui.control.Label
        OutputboxsizeyEditField         matlab.ui.control.NumericEditField
        OutputboxsizexEditFieldLabel    matlab.ui.control.Label
        OutputboxsizexEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        version = 0.2; % Description
    end
    
    properties (Access = public)
        projectpath % Will perform actions on all tiff stacks in $projectpath$.
        exclude % Excludes all files and directories in $exclude$.
        skeletonpath % Loads a skeleton from a .mat file. Make sure the skeleton variable is called $skel$.
        filepaths % paths to files
        output % structure with output variables
        output_wrap % variables stored for saving
        preprocessing_flag = false;
        skeletonization_flag = false;
        analysis_flag = false;
        visualization_flag = false;
        optimizationtools_skeletonization_flag = false;
        optimizationtools_analysis_flag = false;
    end
    
    methods (Access = private)
        
        function computePreprocessing(app)             
            
            if app.DisplayprogressCheckBox.Value
                disp('Computing preprocessing...')
            end
            
            %% Initialize & preallocate
            
            app.output_wrap = struct(...
                'tort_data',[],...
                'dtortuosity',[],...
                'll_distribution',[],...
                'rho_N',[],...
                'rho_L',[],...
                'N_N',[],...
                'N_L',[],...
                'LN_ratio',[]);
            
            [sk_thr,cl_thr,app.filepaths,app.output_wrap.tort_data,shortestpaths,app.output_wrap.dtortuosity,app.output_wrap.ll_distribution,...
                app.output_wrap.rho_N,app.output_wrap.rho_L] = ...
                preloop_initialization(app.projectpath,app.exclude,app.MinimumterminalbranchthresholdEditField.Value,...
                app.MinimumnodedistancethresholdEditField.Value,app.ParticlediametermEditField.Value,app.BinsizeEditField.Value,app.MaximumbinmEditField.Value);
            
            N = size(app.filepaths,2);
            app.output = struct(...
                'sk_thr',cell(N,1),...
                'cl_thr',cell(N,1),...
                'filepaths',cell(N,1),...
                'dxtortuosity',cell(N,1),...
                'dytortuosity',cell(N,1),...
                'dztortuosity',cell(N,1),...
                'dxpath',cell(N,1),...
                'dypath',cell(N,1),...
                'dzpath',cell(N,1),...
                'dxeucl',cell(N,1),...
                'dyeucl',cell(N,1),...
                'dzeucl',cell(N,1),...
                'shortestpaths',cell(N,1),...
                'skel_raw',cell(N,1),...
                'skel',cell(N,1),...
                'Ifl',cell(N,1),...
                'Iraw',cell(N,1),...
                'Ibin',cell(N,1),...
                'Apath',cell(N,1),...
                'Apath_rep',cell(N,1),...
                'node_raw',cell(N,1),...
                'node',cell(N,1),...
                'link',cell(N,1));
                        
            % This function is an adaptation from the main.m script in the
            % Gelscripts_standalone software package
            
            [app.output.sk_thr] = deal(sk_thr);
            [app.output.cl_thr] = deal(cl_thr);
            [app.output.filepaths] = deal(app.filepaths);
            [app.output.shortestpaths] = deal(shortestpaths);
            
            filenames = cell(N,1);
            
            %% Working loops
            
            realpx = [app.PixelsizeXmEditField.Value,app.PixelsizeYmEditField.Value,app.PixelsizeZmEditField.Value];
            Boxsize_input = [app.InputboxsizexEditField.Value,app.InputboxsizeyEditField.Value,app.InputboxsizezEditField.Value];
            Boxsize_output = [app.OutputboxsizexEditField.Value,app.OutputboxsizeyEditField.Value,app.OutputboxsizezEditField.Value];
            Point_spread_function = [app.PointspreadfunctionmxEditField.Value, app.PointspreadfunctionmyEditField.Value, app.PointspreadfunctionmzEditField.Value];
            
            for ii = 1:N
                
                if app.DisplayprogressCheckBox.Value
                    tic;
                end
                
                [pathstr, fname, extension] = fileparts(app.filepaths{ii});
                filenames{ii} = fname;
                
                if strcmp(extension,'.txt') || strcmp(extension,'.xyz')
                    
                    if app.DisplayprogressCheckBox.Value
                        tic;
                    end
                    
                    coord2tif(realpx,app.ParticlediametermEditField.Value,app.filepaths{ii},Boxsize_input,Boxsize_output,Point_spread_function);
                    
                    if app.DisplayprogressCheckBox.Value
                        print_timemin(toc, ['diagram to image stack (' fname ')']);
                    end
                    
                    if app.DisplayprogressCheckBox.Value
                        tic;
                    end
                end
                
                %% Preprocessing
                if app.Maxzframe0allEditField.Value == 0
                    zframes = 'all';
                else
                    zframes = app.Maxzframe0allEditField.Value;
                end
                
                if app.CheckBox.Value
                    binThres = 'auto';
                else
                    binThres = app.BinarizationthresholdEditField.Value;
                end
                
                [app.output(ii).Ifl, app.output(ii).Iraw, app.output(ii).Ibin] = ...
                    project_prep(ii,app.filepaths,zframes,app.Sigmablur2voxel1EditField.Value,...
                    binThres,app.DisplayprogressCheckBox.Value,false,...
                    app.StructuringelementsizevoxelsEditField.Value,app.MinimumfractionEditField.Value);
                
                if app.SavevariablesasmatCheckBox.Value
                    savevariables([pathstr, filesep, 'Output', filesep, fname, filesep,'Preparation_variables.mat'], 'prep', app.output(ii).Ibin, app.output(ii).Ifl)
                end
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['preprocessing (' fname ')']);
                end
                
                if app.DisplayprogressCheckBox.Value
                        tic;
                end
                
                if app.CreateoptimizationtoolsCheckBox.Value
                    visualize_opt_prep(app.output(ii).Iraw,app.output(ii).Ibin,app.output(ii).Ifl,...
                        pathstr,fname);
                end
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['preprocessing visualizations (' fname ')']);
                end
                
                
            end
            app.FilenameDropDown.Items = filenames;
            app.preprocessing_flag = true;
        end
        
        function computeSkeletonization(app)
            
            %% Working loops
            
            N = size(app.filepaths,2);
            
            realpx = [app.PixelsizeXmEditField.Value,app.PixelsizeYmEditField.Value,app.PixelsizeZmEditField.Value];
            
            for ii = 1:N

                %% Skeletonization

                if app.DisplayprogressCheckBox.Value
                    tic;
                end
                
                [~, fname, ~] = fileparts(app.filepaths{ii});
                
                app.output(ii).skel_raw = skeleton3D(app.output(ii).Ifl);
                
                [app.output(ii).skel_raw, app.output(ii).Apath, app.output(ii).node_raw, ~, ~, ~] = wrapper_firstclean(app.output(ii).skel_raw, realpx);
                app.output(ii).Apath_rep = app.output(ii).Apath;
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['skeletonization (' fname ')']);
                end
                    
            end
            app.skeletonization_flag = true;
        end
        
%         function loadSkeletonization(app)
%             disp('Loading skeleton...')
%             app.filepaths = dir([app.skeletonpath filesep '*.mat']);
%             
%             N = size(app.filepaths,2);
%             
%             filenames = cell(N,1);
%             app.output = struct(...
%                 'sk_thr',cell(N,1),...
%                 'cl_thr',cell(N,1),...
%                 'filepaths',cell(N,1),...
%                 'shortestpaths',cell(N,1),...
%                 'skel_raw',cell(N,1),...
%                 'skel',cell(N,1),...
%                 'Ifl',cell(N,1),...
%                 'Iraw',cell(N,1),...
%                 'Ibin',cell(N,1),...
%                 'Apath',cell(N,1),...
%                 'node_raw',cell(N,1),...
%                 'node',cell(N,1),...
%                 'link',cell(N,1));
%             
%             app.output_wrap = struct(...
%                 'tort_data',[],...
%                 'dtortuosity',[],...
%                 'll_distribution',[],...
%                 'rho_N',[],...
%                 'rho_L',[]);
%             
%             for ii = 1:N
%                 
%                 if app.DisplayprogressCheckBox.Value
%                     tic;
%                 end
%                 
%                 [~, fname, ~] = fileparts(app.filepaths{ii});
%                 filenames{ii} = fname;
%                 
%                 in = load();
%                 app.output(ii).skel = in.skel;
%                 app.output(ii).Apath = in.Apath;
%                 app.output(ii).node = in.node;
%                 app.output(ii).link = in.link;
%                 
%                 if app.DisplayprogressCheckBox.Value
%                     print_timemin(toc,['loading skeleton (' fname ')']);
%                 end
%             end
%             app.FilenameDropDown.Items = filenames;
%             app.skeletonization_flag = true;
%         end
            
        function computeAnalysis(app)
            
            if ~app.skeletonization_flag
                msgbox('Please compute/load skeleton.')
                return
            end
            
            if app.DisplayprogressCheckBox.Value
                disp('Computing analysis...')
            end
            
            if app.DisplayprogressCheckBox.Value
                tic;
            end
            
            realpx = [app.PixelsizeXmEditField.Value,app.PixelsizeYmEditField.Value,app.PixelsizeZmEditField.Value];
        
            for ii = 1:size(app.filepaths,2)
                
                [pathstr, fname, ~] = fileparts(app.filepaths{ii});
                
                %% Tortuosity
            
                if app.DisplayprogressCheckBox.Value
                    tic;
                end
                
                [app.output_wrap.tort_data,app.output(ii).shortestpaths,app.output_wrap.dtortuosity,...
                    app.output(ii).dxpath,app.output(ii).dypath,app.output(ii).dzpath,...
                    app.output(ii).dxeucl,app.output(ii).dyeucl,app.output(ii).dzeucl,...
                    app.output(ii).dxtortuosity,app.output(ii).dytortuosity,app.output(ii).dztortuosity] = ...
                    project_tortuosity(ii,app.output(ii).Apath_rep,app.output(ii).node_raw,app.output(ii).skel_raw,realpx,...
                    app.ParticlediametermEditField.Value,app.EdgenodedistanceEditField.Value,...
                    app.output_wrap.tort_data,app.output(ii).shortestpaths,app.output_wrap.dtortuosity);
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['tortuosity (' fname ')']);
                end
            
                %% Thorough cleaning
                
                if app.DisplayprogressCheckBox.Value
                    tic;
                end
                
            	[app.output(ii).skel, app.output(ii).Apath, app.output(ii).node, app.output(ii).link] = ...
                 wrapper_thoroughclean(app.output(ii).skel_raw, app.output(ii).sk_thr, realpx, app.output(ii).cl_thr);
                
                %% Remove all edge nodes and links, if required
                
                if ~app.VisualizeboxedgematerialCheckBox_2.Value
                    [app.output(ii).skel, app.output(ii).Apath, app.output(ii).node, app.output(ii).link] = ...
                        wrapper_edgeclean(app.output(ii).skel, app.EdgenodedistanceEditField.Value,...
                        app.ParticlediametermEditField.Value,realpx);
                end
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['cleaning (' fname ')']);
                end
                
                %% Link length
            
                if app.DisplayprogressCheckBox.Value
                    tic;
                end
                
                Computenodedensity = true;
                switch_linklength = true;
                [app.output_wrap.ll_distribution,app.output_wrap.N_N,app.output_wrap.N_L,app.output_wrap.rho_N,app.output_wrap.rho_L,app.output_wrap.LN_ratio] = ...
                    project_linklength(ii,app.output(ii).skel,...
                    app.output(ii).node,app.output(ii).link,realpx,app.ParticlediametermEditField.Value,...
                    switch_linklength,Computenodedensity,app.MaximumbinmEditField.Value,...
                    app.BinsizeEditField.Value,app.output_wrap.ll_distribution,pathstr,fname,app.output_wrap.N_N,app.output_wrap.N_L,app.output_wrap.rho_N,app.output_wrap.rho_L,app.output_wrap.LN_ratio);
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['link length (' fname ')']);
                end
                
                if app.SavevariablesasmatCheckBox.Value
                    savevariables([pathstr, filesep, 'Output', filesep, fname, filesep,'Skeleton_variables.mat'], 'skel', app.output(ii).skel, app.output(ii).node, app.output(ii).link)
                end
                                            
                if app.SavevariablesasmatCheckBox.Value
                    if app.TortuosityofeachdimensionseparatelyCheckBox.Value
                        savevariables([pathstr, filesep, 'Output', filesep, fname, filesep,'Analysis_variables.mat'], 'ana', ii, app.output_wrap.tort_data,...
                        app.output_wrap.ll_distribution, app.output_wrap.N_N, app.output_wrap.N_L, app.output_wrap.rho_N, app.output_wrap.rho_L, app.output_wrap.LN_ratio,app.output_wrap.dtortuosity,...
                        app.output(ii).dxpath,app.output(ii).dypath,app.output(ii).dzpath,...
                        app.output(ii).dxeucl,app.output(ii).dyeucl,app.output(ii).dzeucl,...
                        app.output(ii).dxtortuosity,app.output(ii).dytortuosity,app.output(ii).dztortuosity)
                    else
                        savevariables([pathstr, filesep, 'Output', filesep, fname, filesep,'Analysis_variables.mat'], 'ana', ii, app.output_wrap.tort_data,...
                        app.output_wrap.ll_distribution, app.output_wrap.N_N, app.output_wrap.N_L, app.output_wrap.rho_N, app.output_wrap.rho_L, app.output_wrap.LN_ratio,app.output_wrap.dtortuosity)
                    end
                end
                
                if app.SaveoutputastxtCheckBox.Value
                    exporttxt_gen(app.output(ii).skel,app.output(ii).node,app.output(ii).link,pathstr,fname);
                    exporttxt_ll([app.output_wrap.ll_distribution(:,1),app.output_wrap.ll_distribution(:,ii+1)],...
                        app.output_wrap.N_N(1,ii),app.output_wrap.N_L(1,ii),app.output_wrap.rho_N(1,ii),...
                        app.output_wrap.rho_L(1,ii),app.output_wrap.LN_ratio(1,ii),pathstr,fname);
                    exporttxt_tort(app.output_wrap.tort_data(ii,:),app.output_wrap.dtortuosity(ii,:),...
                        app.output(ii).dxpath,app.output(ii).dypath,app.output(ii).dzpath,...
                        app.output(ii).dxeucl,app.output(ii).dyeucl,app.output(ii).dzeucl,...
                        app.output(ii).dxtortuosity,app.output(ii).dytortuosity,app.output(ii).dztortuosity,...
                        app.TortuosityofeachdimensionseparatelyCheckBox.Value,pathstr,fname)
                end
                
            end
            
            managePlots(app,'analysis')
            app.analysis_flag = true;

        end
            
        function computeVisualization(app)
            
            if ~app.analysis_flag
                msgbox('Please compute analysis.')
                return
            end
            
            if app.DisplayprogressCheckBox.Value
                disp('Computing visualization...')
            end
            
            realpx = [app.PixelsizeXmEditField.Value,app.PixelsizeYmEditField.Value,app.PixelsizeZmEditField.Value];
        
            for ii = 1:size(app.filepaths,2)
                
                [pathstr, fname, ~] = fileparts(app.filepaths{ii});
                
                %% Visualize skeletonized structures
                if app.DisplayprogressCheckBox.Value
                    tic;
                end
                visualize_skeletons(app.output(ii).skel,app.output(ii).node,app.output(ii).Iraw,realpx,app.SwellskeletonEditField.Value,app.NodesizeEditField.Value...
                    ,app.VisualizeascubicvoxelsCheckBox.Value,app.OutputtypeDropDown.Value,pathstr,fname);
                
                if app.DisplayprogressCheckBox.Value
                    print_timemin(toc,['visualization (' fname ')']);
                end
                
            end
            
            app.visualization_flag = true;
            
        end
        
        function saveOptimizationTools_preprocessing(app)
            
%             if ~app.skeletonization_flag
%                 msgbox('Please compute skeletonization.')
%                 return
%             end
%                 
%             if app.DisplayprogressCheckBox.Value
%                 disp('Computing optimization tools (for skeletonization)...')
%             end
%             
%             realpx = [app.PixelsizeXmEditField.Value,app.PixelsizeYmEditField.Value,app.PixelsizeZmEditField.Value];
%         
%             for ii = 1:size(app.filepaths,2)
%                 
%                 [pathstr, fname, ~] = fileparts(app.filepaths{ii});
%                 
%                 %% Uncleaned skeleton visualization
%                 
%                 if app.CreateoptimizationtoolsCheckBox.Value
%                     
%                     visualize_preclean(app.output(ii).skel_raw,app.output(ii).node_raw,app.output(ii).Iraw,app.output(ii).Ifl,...
%                         app.SwellskeletonEditField.Value,pathstr,fname,app.NodesizeEditField.Value,...
%                         realpx,app.OutputtypeDropDown.Value,app.VisualizeascubicvoxelsCheckBox.Value);
%                     
%                 end
%                 
%             end
            
            app.optimizationtools_skeletonization_flag = true;
            
        end
        
        function saveOptimizationTools_skeletonization(app)
            
            if ~app.skeletonization_flag
                msgbox('Please compute skeletonization.')
                return
            end
                
            if app.DisplayprogressCheckBox.Value
                disp('Computing optimization tools (for skeletonization)...')
            end
            
            realpx = [app.PixelsizeXmEditField.Value,app.PixelsizeYmEditField.Value,app.PixelsizeZmEditField.Value];
        
            for ii = 1:size(app.filepaths,2)
                
                [pathstr, fname, ~] = fileparts(app.filepaths{ii});
                
                %% Uncleaned skeleton visualization
                
                if app.CreateoptimizationtoolsCheckBox.Value
                    
                    visualize_preclean(app.output(ii).skel_raw,app.output(ii).node_raw,app.output(ii).Iraw,app.output(ii).Ifl,...
                        app.SwellskeletonEditField.Value,pathstr,fname,app.NodesizeEditField.Value,...
                        realpx,app.OutputtypeDropDown.Value,app.VisualizeascubicvoxelsCheckBox.Value);
                    
                end
                
            end
            
            app.optimizationtools_skeletonization_flag = true;
            
        end
        
        function saveOptimizationTools_analysis(app)
            
            if ~app.analysis_flag
                msgbox('Please compute analysis.')
                return
            end
                
            if app.DisplayprogressCheckBox.Value
                disp('Computing optimization tools (for analysis)...')
            end
            
        
            for ii = 1:size(app.filepaths,2)
                
                [pathstr, fname, ~] = fileparts(app.filepaths{ii});
            	%% Save skeleton, if required
            
                if app.CreateoptimizationtoolsCheckBox.Value
                    skel = app.output(ii).skel;
                    Apath = app.output(ii).Apath;
                    node = app.output(ii).node;
                    link = app.output(ii).link;
                    save([pathstr,'/Output/',fname,'/skeleton.mat'],'skel','Apath','node','link');
                    clear skel Apath node link % clear duplicates
                end
                
                %% Create optimization tool tiff stacks
                
                if app.CreateoptimizationtoolsCheckBox.Value
                    visualize_optimizations(app.output(ii).skel,app.output(ii).node,app.output(ii).skel_raw, app.output(ii).node_raw,app.output(ii).Iraw,app.output(ii).Ibin,app.output(ii).Ifl,...
                        app.SwellskeletonEditField.Value,pathstr,fname,app.NodesizeEditField.Value);
                    
                end
                
            end
            
            app.optimizationtools_analysis_flag = true;
            
        end
            
        function saveProject(app)
            
            if ~app.analysis_flag
                msgbox('Please compute analysis.')
                return
            end
            
            if app.DisplayprogressCheckBox.Value
                disp('Saving project...')
            end
            

            %% wrap-up and data-saving
            
            if app.DisplayprogressCheckBox.Value
                tic;
            end
            
            [~] = wrapup_tortuosity(app.output_wrap.tort_data,app.projectpath,app.output_wrap.dtortuosity...
                ,app.SaveoutputastxtCheckBox.Value,pathstr);
            
            [~] = wrapup_linklength(app.output_wrap.ll_distribution,app.MaximumbinmEditField.Value...
                ,true,app.projectpath);
            
%             if app.SaveoptimizationtoolsCheckBox_analysis.Value
%                 save([app.projectpath,filesep,'Output',filesep,'all_variables.mat'],'app.output','app.output_wrap');
%             end
            
            if app.DisplayprogressCheckBox.Value
                print_timemin(toc,'saving all variables');
            end
    
        end
        
        function managePlots(app,mode)
            
            % specify plot options
            switch mode
                case 'preprocessing'
                    resetflag = true;
                    plots = {...
                        'Raw data',''... % empty is interpreted as path to input file
                        };
                    clearplots = [];
                case 'skeletonization'
                    resetflag = false;
                    plots = {...
                        };
                    clearplots = [];
                case 'analysis'
                    resetflag = false;
                    plots = {'Node distance histogram','Nodedistance.fig'...
                        };
                    clearplots = [];
                case 'optimization_tools_preprocessing'
                    resetflag = false;
                    plots = {...
                        'Final binarization & initial binarization, overlaid','Binarized_cleaning.tif';...
                        'Raw image & initial binarization, overlaid','Rawimage_binarized.tif'...
                        };
                    clearplots = [];                    
                case 'optimization_tools_skeletonization'
                    resetflag = false;
                    if strcmp(app.OutputtypeDropDown.Value,'matlab')
                        plots = {...
                            'Skeleton & uncleaned binarization, overlaid','Post-skeletonization_uncleaned.tif';...
                            'Raw image & uncleaned skeleton, overlaid','Rawimage_uncleaned-skeleton.tif';...
                            'Uncleaned skeleton projection, rotatable','Projection_uncleaned.fig';...
                            'Uncleaned skeleton projection, XY-Z','Projection_xy-z_uncleaned.png';...
                            'Uncleaned skeleton projection, XYZ','Projection_xyz_uncleaned.png';...
                            'Uncleaned skeleton projection, -XY-Z','Projection_-xy-z_uncleaned.png';...
                            'Uncleaned skeleton projection, -XYZ','Projection_-xyz_uncleaned.png'...
                            };
                        clearplots = [];
                    elseif strcmp(app.OutputtypeDropDown.Value,'vtk')
                        plots = {...
                            'Skeleton, uncleaned','Post-skeletonization_uncleaned.tif';...
                            'Raw image & uncleaned skeleton, overlaid','Rawimage_uncleaned-skeleton.tif';...
                            };
                        clearplots = {...
                            'Uncleaned skeleton projection, rotatable',...
                            'Uncleaned skeleton projection, XY-Z',...
                            'Uncleaned skeleton projection, XYZ',...
                            'Uncleaned skeleton projection, -XY-Z',...
                            'Uncleaned skeleton projection, -XYZ'...
                            };
                    else
                        clearplots = [];
                        plots = {};
                    end
                    
                case 'optimization_tools_analysis'
                    resetflag = false;
                    plots = {...
                        };
                    clearplots = [];
                case 'visualization'
                    resetflag = false;
                    if strcmp(app.OutputtypeDropDown.Value,'matlab')
                        plots = {...
                            'Final skeleton & raw data, overlaid','Rawimage_skeleton.tif';...
                            'Cleaned skeleton projection, rotatable','Projection_cleaned.fig';...
                            'Cleaned skeleton projection, XY-Z','Projection_-xy-z_cleaned.png';...
                            'Cleaned skeleton projection, XYZ','Projection_xyz_cleaned.png';...
                            'Cleaned skeleton projection, -XY-Z','Projection_xy-z_cleaned.png';...
                            'Cleaned skeleton projection, -XYZ','Projection_-xyz_cleaned.png'...
                            };
                        clearplots = [];
                    elseif strcmp(app.OutputtypeDropDown.Value,'vtk')
                        plots = {...
                            'Final skeleton & raw data, overlaid','Rawimage_skeleton.tif'...
                            };
                        clearplots = {...
                            'Cleaned skeleton projection, rotatable',...
                            'Cleaned skeleton projection, XY-Z',...
                            'Cleaned skeleton projection, XYZ',...
                            'Cleaned skeleton projection, -XY-Z',...
                            'Cleaned skeleton projection, -XYZ'...
                            };
                    else
                        clearplots = [];
                        plots = {};
                    end
                    
                case 'optimization_tools_visualization'
                    resetflag = false;
                    if strcmp(app.OutputtypeDropDown.Value,'matlab')
                        plots = {...
                            'Pre- & post cleaning skeletons, overlaid','Rawimage_skeletoncleaning.tif';...
                            'Final skeleton & binarization, overlaid','Binarized_skeleton.tif';...
                            'Final skeleton & raw data, overlaid','Rawimage_skeleton.tif';...
                            'Cleaned skeleton projection, rotatable','Projection_cleaned.fig';...
                            'Cleaned skeleton projection, XY-Z','Projection_-xy-z_cleaned.png';...
                            'Cleaned skeleton projection, XYZ','Projection_xyz_cleaned.png';...
                            'Cleaned skeleton projection, -XY-Z','Projection_xy-z_cleaned.png';...
                            'Cleaned skeleton projection, -XYZ','Projection_-xyz_cleaned.png'...
                            };
                        clearplots = [];
                    elseif strcmp(app.OutputtypeDropDown.Value,'vtk')
                        plots = {...
                            'Pre- & post cleaning skeletons, overlaid','Rawimage_skeletoncleaning.tif';...
                            'Final skeleton & binarization, overlaid','Binarized_skeleton.tif';...
                            'Final skeleton & raw data, overlaid','Rawimage_skeleton.tif'...
                            };
                        clearplots = {...
                            'Cleaned skeleton projection, rotatable',...
                            'Cleaned skeleton projection, XY-Z',...
                            'Cleaned skeleton projection, XYZ',...
                            'Cleaned skeleton projection, -XY-Z',...
                            'Cleaned skeleton projection, -XYZ'...
                            };
                    else
                        clearplots = [];
                        plots = {};
                    end
                    
            end
            
            % update display with plot options
            if resetflag
                if isempty(plots)
                    app.ListBox.Items = cell(0);
                    app.ListBox.ItemsData = cell(0);
                    app.ListBox.Value = cell(0);
                else
                    app.ListBox.Items = plots(:,1);
                    app.ListBox.ItemsData = plots(:,2);
                    app.ListBox.Value = plots{1,2};
                end
            else
                if ~isempty(clearplots)
                    bool_items = ismember(app.ListBox.Items,clearplots);
                    app.ListBox.Items(bool_items) = [];
                    app.ListBox.ItemsData(bool_items) = [];
                end
                if isempty(plots)
                    return
                end
                bool_plots = ismember(plots(:,1),app.ListBox.Items);
                app.ListBox.Items = cat(2,app.ListBox.Items,plots(~bool_plots,1)');
                app.ListBox.ItemsData = cat(2,app.ListBox.ItemsData,plots(~bool_plots,2)');
            end
            
            
        end
        
        function wrapup_app(app)
            
            Tortuosity_data = app.output_wrap.tort_data;
            Tortuosity_xyz = app.output_wrap.dtortuosity;
            ll_distributions = app.output_wrap.ll_distribution;
            Node_density = app.output_wrap.rho_N;
            Link_density = app.output_wrap.rho_L;
            Node_number = app.output_wrap.N_N;
            Link_number = app.output_wrap.N_L;
            Link_to_node_ratio = app.output_wrap.LN_ratio;

            Tort_summary = wrapup_tortuosity(Tortuosity_data,Tortuosity_xyz,app.SaveoutputastxtCheckBox.Value);
            
            ll_summary = zeros(size(ll_distributions,1),3);
            ll_summary(:,1) = ll_distributions(:,1);
            ll_summary(:,2) = mean(ll_distributions(:,2:end),2);
            ll_summary(:,3) = std(ll_distributions(:,2:end),0,2);
            
            Noderho_summary = zeros(1,2);
            Noderho_summary(1,1) = mean(Node_density,2);
            Noderho_summary(1,2) = std(Node_density,0,2);
            
            Linkrho_summary = zeros(1,2);
            Linkrho_summary(1,1) = mean(Link_density,2);
            Linkrho_summary(1,2) = std(Link_density,0,2);
            
            Nodeno_summary = zeros(1,2);
            Nodeno_summary(1,1) = mean(Node_number,2);
            Nodeno_summary(1,2) = std(Node_number,0,2);
            
            Linkno_summary = zeros(1,2);
            Linkno_summary(1,1) = mean(Link_number,2);
            Linkno_summary(1,2) = std(Link_number,0,2);
            
            Linknoderatio_summary = zeros(1,2);
            Linknoderatio_summary(1,1) = mean(Link_to_node_ratio,2);
            Linknoderatio_summary(1,2) = std(Link_to_node_ratio,0,2);
            
            sfname = [app.projectpath, filesep, 'Output', filesep, 'Analysis_output_combined.mat'];
            save(sfname,'Tortuosity_data', 'Tortuosity_xyz','ll_distributions','Node_number',...
                'Link_number','Node_density','Link_density', 'Link_to_node_ratio','Noderho_summary',...
                'Linkrho_summary','Nodeno_summary','Linkno_summary','Linknoderatio_summary',...
                'll_summary','Tort_summary')
            
            if app.SaveoutputastxtCheckBox.Value
                wrapup_tort(Tort_summary,app.projectpath);
                wrapup_ll(ll_summary,Nodeno_summary,Linkno_summary,Noderho_summary,Linkrho_summary,Linknoderatio_summary,app.projectpath);
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Visible = 0; % make invisible here to allow editing in App Designer
            disp(['Skeletonization-Based Analysis Routines For Colloidal Gels v. ' num2str(app.version)])
            disp(char(datetime('now')))
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')),['.' filesep])))
        end

        % Button pushed function: ProjectfolderButton
        function ProjectfolderButtonPushed(app, event)
            out = uigetdir('Please select the folder containing the (.tif) image stacks');
            if out == 0
                % do nothing
            else
                fprintf('Selected project folder: \n%s\n',out)
                app.projectpath = out;
                app.ProjectfolderButton.BackgroundColor = [0.39,0.83,0.07];
                cd(app.projectpath)
            end
        end

        % Value changed function: ShowfinetuningoptionsCheckBox
        function ShowfinetuningoptionsCheckBoxValueChanged(app, event)
            value = app.ShowfinetuningoptionsCheckBox.Value;
            app.TabGroup.Visible = value;
        end

        % Button pushed function: ExcludefilesfromprojectButton
        function ExcludefilesfromprojectButtonPushed(app, event)
            
            % check if a project folder has been selected
            if isempty(app.projectpath)
                msgbox('Please select a project folder.')
                return
            end
            cd(app.projectpath)
            [file,~,idx] = uigetfile({'*.tif;*.tiff','Image stack files (*.tif,*.tiff)'},'Please select the files that have to be excluded for analysis','MultiSelect','on');
            if idx == 0
                % do nothing
            else
                if iscell(file)
                    print_str = strjoin(file,'\n');
                else
                    print_str = file;
                end
                fprintf('Excluded files from project: \n%s\n',print_str)
                app.exclude = file;
                app.ExcludefilesfromprojectButton.BackgroundColor = [0.39,0.83,0.07];
            end
        end

        % Callback function
        function SkeletonfolderButtonPushed(app, event)
            out = uigetdir('Please select the folder containing the (.mat) skeleton');
            if out == 0
                % do nothing
            else
                fprintf('Selected skeleton folder: \n%s\n',out)
                app.skeletonpath = out;
                app.SkeletonfolderButton.BackgroundColor = [0.39,0.83,0.07];
                app.LoadfromskeletonfolderCheckBox.Value = 1;
            end
        end

        % Button pushed function: RunfullanalysisButton
        function RunfullanalysisButtonPushed(app, event)
            % check if a project folder has been selected
            if isempty(app.projectpath)
                msgbox('Please select a project folder.')
                return
            end
            disp('Started full process.')
            % check if a skeleton folder has been selected
%             if app.LoadfromskeletonfolderCheckBox.Value && isempty(app.skeletonpath)
%                msgbox('Please select a skeleton folder.')
%                return
%             end
            
            computePreprocessing(app)
            managePlots(app,'preprocessing')
            if app.CreateoptimizationtoolsCheckBox.Value
                saveOptimizationTools_preprocessing(app)
                managePlots(app,'optimization_tools_preprocessing')
            end
            computeSkeletonization(app)
            managePlots(app,'skeletonization')
            if app.CreateoptimizationtoolsCheckBox.Value
                saveOptimizationTools_skeletonization(app)
                managePlots(app,'optimization_tools_skeletonization')
            end
            computeAnalysis(app)
            managePlots(app,'analysis')
            if app.CreateoptimizationtoolsCheckBox.Value
                saveOptimizationTools_analysis(app)
                managePlots(app,'optimization_tools_analysis')
            end
            computeVisualization(app)
            managePlots(app,'visualization')
            wrapup_app(app)
            disp('Done')
        end

        % Button pushed function: RunskeletonizationButton
        function RunskeletonizationButtonPushed(app, event)
            % check if a project folder has been selected
            if isempty(app.projectpath)
                msgbox('Please select a project folder.')
                return
            end
            disp('Started skeletonization.')

            % check if a skeleton folder has been selected
%             if app.LoadfromskeletonfolderCheckBox.Value && isempty(app.skeletonpath)
%                 msgbox('Please select a skeleton folder.')
%                 return
%             end
            
            computeSkeletonization(app)
            managePlots(app,'skeletonization')
            if app.CreateoptimizationtoolsCheckBox.Value
                saveOptimizationTools_skeletonization(app)
                managePlots(app,'optimization_tools_skeletonization')
            end
            disp('Done')
        end

        % Button pushed function: RunanalysisButton_2
        function RunanalysisButton_2Pushed(app, event)
            if isempty(app.projectpath)
                msgbox('Please select a project folder.')
                return
            end
            disp('Started analysis.')

            
            computeAnalysis(app)
            managePlots(app,'analysis')
            if app.CreateoptimizationtoolsCheckBox.Value
                saveOptimizationTools_analysis(app)
                managePlots(app,'optimization_tools_analysis')
            end
            disp('Done')
        end

        % Button pushed function: RunvisualizationButton
        function RunvisualizationButtonPushed(app, event)
            if isempty(app.projectpath)
                msgbox('Please select a project folder.')
                return
            end
            disp('Started preparing visualizations.')
            
            computeVisualization(app)
            managePlots(app,'visualization')
            disp('Done')
        end

        % Callback function
        function SavevariablesButtonPushed(app, event)
            saveProject(app)
            disp('Done')
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            if ~strcmp(app.FilenameDropDown.Value,'No files available')
                if isempty(app.ListBox.Value)
                    readnplay(app.filepaths{strcmp(app.FilenameDropDown.Items,app.FilenameDropDown.Value)},app.projectpath) % open raw image file
                else
                    readnplay([app.projectpath filesep 'Output' filesep app.FilenameDropDown.Value filesep app.ListBox.Value],app.projectpath)
                end
            end
        end

        % Button pushed function: RunpreprocessingButton
        function RunpreprocessingButtonPushed(app, event)
            if isempty(app.projectpath)
                msgbox('Please select a project folder.')
                return
            end
            disp('Started preprocessing.')
            
            computePreprocessing(app)
            managePlots(app,'preprocessing')
            if app.CreateoptimizationtoolsCheckBox.Value
                saveOptimizationTools_preprocessing(app)
                managePlots(app,'optimization_tools_preprocessing')
            end
            disp('Done')
        end

        % Button pushed function: ResetdefaultvaluesButton
        function ResetdefaultvaluesButtonPushed(app, event)
            app.Sigmablur2voxel1EditField.Value = 2;
            app.CheckBox.Value = 1;
            app.BinarizationthresholdEditField.Value = 0;
            app.StructuringelementsizevoxelsEditField.Value = 5;
            app.MinimumfractionEditField.Value = 0.001;
            app.MinimumterminalbranchthresholdEditField.Value = 1.5;
            app.MinimumnodedistancethresholdEditField.Value = 0.9;
            app.EdgenodedistanceEditField.Value = 3;
            app.VisualizeboxedgematerialCheckBox_2.Value = 0;
            app.BinsizeEditField.Value = 250;
            app.MaximumbinmEditField.Value = 25;
            app.TortuosityofeachdimensionseparatelyCheckBox.Value = 0;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            app.BinarizationthresholdEditField.Editable = 1 - value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Gelscripts_GUIUIFigure = uifigure('Visible', 'off');
            app.Gelscripts_GUIUIFigure.Name = 'Gelscripts_GUI';
            app.TabGroup = uitabgroup(app.Gelscripts_GUIUIFigure);
            app.TabGroup.Tooltip = {'The following parameters finetune certain aspects of different analyses. All of these are set to ''auto'' by default, which performs an action with an automatic solution. Fine tuning can improve analyses in specific cases.'};
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.Sigmablur2voxel1EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Sigmablur2voxel1EditFieldLabel.Text = {'Sigma blur'; '[2·voxel + 1]'};
            app.Sigmablur2voxel1EditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.Sigmablur2voxel1EditField.LowerLimitInclusive = 'off';
            app.Sigmablur2voxel1EditField.UpperLimitInclusive = 'off';
            app.Sigmablur2voxel1EditField.Limits = [0 Inf];
            app.Sigmablur2voxel1EditField.Tooltip = {'Sigma in Gaussian blur kernel; (2 * σ blur + 1) should ideally be smaller than a particle radius.'};
            app.Sigmablur2voxel1EditField.Value = 1;
            app.CheckBox = uicheckbox(app.PreprocessingTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.AutomaticbinarizationthresholdLabel = uilabel(app.PreprocessingTab);
            app.AutomaticbinarizationthresholdLabel.Tooltip = {'Tick to use the Otsu automated binarization thresholding method.'};
            app.AutomaticbinarizationthresholdLabel.Text = {'Automatic'; 'binarization threshold'};
            app.BinarizationthresholdEditFieldLabel = uilabel(app.PreprocessingTab);
            app.BinarizationthresholdEditFieldLabel.Text = 'Binarization threshold';
            app.BinarizationthresholdEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.BinarizationthresholdEditField.UpperLimitInclusive = 'off';
            app.BinarizationthresholdEditField.Limits = [0 Inf];
            app.BinarizationthresholdEditField.Editable = 'off';
            app.BinarizationthresholdEditField.Tooltip = {'Set threshold for binarization, from 0 to 1. Tick above box for the Otsu method.'};
            app.StructuringelementsizevoxelsEditFieldLabel = uilabel(app.PreprocessingTab);
            app.StructuringelementsizevoxelsEditFieldLabel.Text = {'Structuring element'; 'size (voxels)'};
            app.StructuringelementsizevoxelsEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.StructuringelementsizevoxelsEditField.LowerLimitInclusive = 'off';
            app.StructuringelementsizevoxelsEditField.UpperLimitInclusive = 'off';
            app.StructuringelementsizevoxelsEditField.Limits = [0 Inf];
            app.StructuringelementsizevoxelsEditField.RoundFractionalValues = 'on';
            app.StructuringelementsizevoxelsEditField.Tooltip = {'Radius of the spherical structuring element for morphological closing, in voxels.'};
            app.StructuringelementsizevoxelsEditField.Value = 6;
            app.MinimumfractionEditFieldLabel = uilabel(app.PreprocessingTab);
            app.MinimumfractionEditFieldLabel.Text = 'Minimum fraction';
            app.MinimumfractionEditField = uieditfield(app.PreprocessingTab, 'numeric');
            app.MinimumfractionEditField.Tooltip = {'All objects smaller than minimum fraction*max. object are removed before skeletonization.'};
            app.MinimumfractionEditField.Value = 0.001;
            app.RunpreprocessingButton = uibutton(app.PreprocessingTab, 'push');
            app.RunpreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunpreprocessingButtonPushed, true);
            app.RunpreprocessingButton.Tooltip = {'Runs the preprocessing section of the code.'};
            app.RunpreprocessingButton.Text = 'Run preprocessing';
            app.SkeletonizationTab = uitab(app.TabGroup);
            app.SkeletonizationTab.Title = 'Skeletonization';
            app.MinimumterminalbranchthresholdEditFieldLabel = uilabel(app.SkeletonizationTab);
            app.MinimumterminalbranchthresholdEditFieldLabel.Text = {'Minimum terminal branch'; 'threshold [σ]'};
            app.MinimumterminalbranchthresholdEditField = uieditfield(app.SkeletonizationTab, 'numeric');
            app.MinimumterminalbranchthresholdEditField.UpperLimitInclusive = 'off';
            app.MinimumterminalbranchthresholdEditField.Limits = [0 Inf];
            app.MinimumterminalbranchthresholdEditField.Tooltip = {'Throws away all terminal skeleton branches that are smaller than this threshold. Unit is particle diameter.'};
            app.MinimumterminalbranchthresholdEditField.Value = 2;
            app.MinimumnodedistancethresholdEditFieldLabel = uilabel(app.SkeletonizationTab);
            app.MinimumnodedistancethresholdEditFieldLabel.Tooltip = {'Collects all nodes that exist in close vicinity of each other in a single node. Close vicinity is defined by this parameter in units of particle diameter.'};
            app.MinimumnodedistancethresholdEditFieldLabel.Text = {'Minimum node distance'; 'threshold [σ]'};
            app.MinimumnodedistancethresholdEditField = uieditfield(app.SkeletonizationTab, 'numeric');
            app.MinimumnodedistancethresholdEditField.UpperLimitInclusive = 'off';
            app.MinimumnodedistancethresholdEditField.Limits = [0 Inf];
            app.MinimumnodedistancethresholdEditField.Tooltip = {'Collects any nodes that are closer together than this threshold, units in particle diameter. It is recommended to have coll_skel_thr <= term_skel_thr.'};
            app.MinimumnodedistancethresholdEditField.Value = 0.9;
            app.RunskeletonizationButton = uibutton(app.SkeletonizationTab, 'push');
            app.RunskeletonizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunskeletonizationButtonPushed, true);
            app.RunskeletonizationButton.Tooltip = {'Runs the skeletonization section of the code.'};
            app.RunskeletonizationButton.Text = 'Run skeletonization';
            app.EdgenodedistanceEditFieldLabel = uilabel(app.SkeletonizationTab);
            app.EdgenodedistanceEditFieldLabel.Tooltip = {'If a node, link or skeleton segment is closer than this amount to the box edge, it is counted as intersecting the box edge. This is necessary for tortuosity calculations,  and is corrected for in node- and link parameter analyses. '};
            app.EdgenodedistanceEditFieldLabel.Text = 'Edge node distance [σ]';
            app.EdgenodedistanceEditField = uieditfield(app.SkeletonizationTab, 'numeric');
            app.EdgenodedistanceEditField.UpperLimitInclusive = 'off';
            app.EdgenodedistanceEditField.Limits = [0 Inf];
            app.EdgenodedistanceEditField.Tooltip = {'Disregards all nodes and links that exist nearer than this value to the bounding box, in micrometer.'};
            app.EdgenodedistanceEditField.Value = 3;
            app.VisualizeboxedgematerialCheckBox_2 = uicheckbox(app.SkeletonizationTab);
            app.VisualizeboxedgematerialCheckBox_2.Tooltip = {'If ticked, skeleton material and nodes close to the box edge, and links leading to the box edge, will be visualized. This is only visualization, and has no effect on analysis results.'};
            app.VisualizeboxedgematerialCheckBox_2.Text = 'Visualize box edge material';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.TortuosityofeachdimensionseparatelyCheckBox = uicheckbox(app.AnalysisTab);
            app.TortuosityofeachdimensionseparatelyCheckBox.Tooltip = {'Turns on the separate tortuosity output in the x-, y- and z- directions.'};
            app.TortuosityofeachdimensionseparatelyCheckBox.Text = 'Tortuosity of each dimension separately';
            app.BinsizeEditFieldLabel = uilabel(app.AnalysisTab);
            app.BinsizeEditFieldLabel.Text = 'Bin size';
            app.BinsizeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.BinsizeEditField.LowerLimitInclusive = 'off';
            app.BinsizeEditField.UpperLimitInclusive = 'off';
            app.BinsizeEditField.Limits = [0 Inf];
            app.BinsizeEditField.RoundFractionalValues = 'on';
            app.BinsizeEditField.Tooltip = {'Sets the bin size for the link length distribution calculation.'};
            app.BinsizeEditField.Value = 250;
            app.MaximumbinmLabel = uilabel(app.AnalysisTab);
            app.MaximumbinmLabel.Text = 'Maximum bin [μm]';
            app.MaximumbinmEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaximumbinmEditField.LowerLimitInclusive = 'off';
            app.MaximumbinmEditField.UpperLimitInclusive = 'off';
            app.MaximumbinmEditField.Limits = [0 Inf];
            app.MaximumbinmEditField.Tooltip = {'Sets the maximum bin in the link length calculations, in µm.'};
            app.MaximumbinmEditField.Value = 25;
            app.RunanalysisButton_2 = uibutton(app.AnalysisTab, 'push');
            app.RunanalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_2Pushed, true);
            app.RunanalysisButton_2.Tooltip = {'Runs the analysis section of the code.'};
            app.RunanalysisButton_2.Text = 'Run analysis';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizeascubicvoxelsCheckBox = uicheckbox(app.VisualizationTab);
            app.VisualizeascubicvoxelsCheckBox.Tooltip = {'Represents every voxel as if it was cubic.'};
            app.VisualizeascubicvoxelsCheckBox.Text = 'Visualize as cubic voxels';
            app.OutputtypeDropDownLabel = uilabel(app.VisualizationTab);
            app.OutputtypeDropDownLabel.Text = 'Output type';
            app.OutputtypeDropDown = uidropdown(app.VisualizationTab);
            app.OutputtypeDropDown.Items = {'matlab', 'vtk'};
            app.OutputtypeDropDown.Tooltip = {'If ''matlab'', outputs 3D renders from matlab, if ''vtk'', outputs vtk files for external use.'};
            app.OutputtypeDropDown.Value = 'matlab';
            app.SwellskeletonEditFieldLabel = uilabel(app.VisualizationTab);
            app.SwellskeletonEditFieldLabel.Tooltip = {'Visualize every voxel of the skeleton as a cube of '};
            app.SwellskeletonEditFieldLabel.Text = 'Swell skeleton';
            app.SwellskeletonEditField = uieditfield(app.VisualizationTab, 'numeric');
            app.SwellskeletonEditField.LowerLimitInclusive = 'off';
            app.SwellskeletonEditField.UpperLimitInclusive = 'off';
            app.SwellskeletonEditField.Limits = [0 Inf];
            app.SwellskeletonEditField.RoundFractionalValues = 'on';
            app.SwellskeletonEditField.Tooltip = {'Swells the skeleton in 3D rendering, only in visualizations. Has to be an integer. High integers cause very long calculation times.'};
            app.SwellskeletonEditField.Value = 2;
            app.NodesizeEditFieldLabel = uilabel(app.VisualizationTab);
            app.NodesizeEditFieldLabel.Text = 'Node size ';
            app.NodesizeEditField = uieditfield(app.VisualizationTab, 'numeric');
            app.NodesizeEditField.Tooltip = {'Pixel radius of nodes in the 3D renderings. Has to be an integer, 0 turns it off. High integers cause very long calculation times.'};
            app.NodesizeEditField.Value = 3;
            app.RunvisualizationButton = uibutton(app.VisualizationTab, 'push');
            app.RunvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunvisualizationButtonPushed, true);
            app.RunvisualizationButton.Tooltip = {'Runs the visualization section of the code.'};
            app.RunvisualizationButton.Text = 'Run visualization';
            app.AnalysisPanel = uipanel(app.Gelscripts_GUIUIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.RunfullanalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.RunfullanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunfullanalysisButtonPushed, true);
            app.RunfullanalysisButton.Tooltip = {'Runs the full analysis.'};
            app.RunfullanalysisButton.Text = 'Run full analysis';
            app.DisplayprogressCheckBox = uicheckbox(app.AnalysisPanel);
            app.DisplayprogressCheckBox.Tooltip = {'Displays current working section and times in the command box.'};
            app.DisplayprogressCheckBox.Text = 'Display progress';
            app.DisplayprogressCheckBox.Value = true;
            app.CreateoptimizationtoolsCheckBox = uicheckbox(app.AnalysisPanel);
            app.CreateoptimizationtoolsCheckBox.Tooltip = {'Creates visualizations that help with choosing optimum fine-tuning parameters.'};
            app.CreateoptimizationtoolsCheckBox.Text = 'Create optimization tools';
            app.SavevariablesasmatCheckBox = uicheckbox(app.AnalysisPanel);
            app.SavevariablesasmatCheckBox.Tooltip = {'Saves all variables that are produced in this run in a .mat file to be used further.'};
            app.SavevariablesasmatCheckBox.Text = 'Save variables as .mat';
            app.SavevariablesasmatCheckBox.Value = true;
            app.SaveoutputastxtCheckBox = uicheckbox(app.AnalysisPanel);
            app.SaveoutputastxtCheckBox.Tooltip = {'Saves all variables that are produced in this run in a .mat file to be used further.'};
            app.SaveoutputastxtCheckBox.Text = 'Save output as .txt';
            app.SaveoutputastxtCheckBox.Value = true;
            app.VisualizationPanel = uipanel(app.Gelscripts_GUIUIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.FilenameDropDownLabel = uilabel(app.VisualizationPanel);
            app.FilenameDropDownLabel.Text = 'File name';
            app.FilenameDropDown = uidropdown(app.VisualizationPanel);
            app.FilenameDropDown.Items = {'No files available'};
            app.FilenameDropDown.Value = 'No files available';
            app.ListBox = uilistbox(app.VisualizationPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.VisualizeButton = uibutton(app.VisualizationPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.GeneralInputPanel = uitabgroup(app.Gelscripts_GUIUIFigure);
            app.GeneralsettingsTab = uitab(app.GeneralInputPanel);
            app.GeneralsettingsTab.Title = 'General settings';
            app.ProjectfolderButton = uibutton(app.GeneralsettingsTab, 'push');
            app.ProjectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectfolderButtonPushed, true);
            app.ProjectfolderButton.Tooltip = {'Will perform actions on all tiff stacks in $projectpath$.'};
            app.ProjectfolderButton.Text = 'Project folder';
            app.ExcludefilesfromprojectButton = uibutton(app.GeneralsettingsTab, 'push');
            app.ExcludefilesfromprojectButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludefilesfromprojectButtonPushed, true);
            app.ExcludefilesfromprojectButton.Tooltip = {'Excludes all files and directories in $exclude$.'};
            app.ExcludefilesfromprojectButton.Text = 'Exclude files from project';
            app.ShowfinetuningoptionsCheckBox = uicheckbox(app.GeneralsettingsTab);
            app.ShowfinetuningoptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowfinetuningoptionsCheckBoxValueChanged, true);
            app.ShowfinetuningoptionsCheckBox.Tooltip = {'Show advanced options for finetuning.'};
            app.ShowfinetuningoptionsCheckBox.Text = 'Show fine-tuning options';
            app.Maxzframe0allLabel = uilabel(app.GeneralsettingsTab);
            app.Maxzframe0allLabel.Text = 'Max. z-frame (0 = all)';
            app.Maxzframe0allEditField = uieditfield(app.GeneralsettingsTab, 'numeric');
            app.Maxzframe0allEditField.UpperLimitInclusive = 'off';
            app.Maxzframe0allEditField.Limits = [0 Inf];
            app.Maxzframe0allEditField.RoundFractionalValues = 'on';
            app.Maxzframe0allEditField.Tooltip = {'The highest slice of the tiff stack that will be considered. If 0, all frames will be imaged.'};
            app.PixelsizeXmLabel = uilabel(app.GeneralsettingsTab);
            app.PixelsizeXmLabel.Text = 'Pixel size X [μm]';
            app.PixelsizeXmEditField = uieditfield(app.GeneralsettingsTab, 'numeric');
            app.PixelsizeXmEditField.UpperLimitInclusive = 'off';
            app.PixelsizeXmEditField.Limits = [0 Inf];
            app.PixelsizeXmEditField.Tooltip = {'X-dimension of a single voxel. Used to calculate sizes, choose units carefully. By default in micrometer.'};
            app.PixelsizeXmEditField.Value = 0.05;
            app.PixelsizeYmLabel = uilabel(app.GeneralsettingsTab);
            app.PixelsizeYmLabel.Text = 'Pixel size Y [μm]';
            app.PixelsizeYmEditField = uieditfield(app.GeneralsettingsTab, 'numeric');
            app.PixelsizeYmEditField.UpperLimitInclusive = 'off';
            app.PixelsizeYmEditField.Limits = [0 Inf];
            app.PixelsizeYmEditField.Tooltip = {'Y-dimension of a single voxel. Used to calculate sizes, choose units carefully. By default in micrometer.'};
            app.PixelsizeYmEditField.Value = 0.05;
            app.PixelsizeZmLabel = uilabel(app.GeneralsettingsTab);
            app.PixelsizeZmLabel.Text = 'Pixel size Z [μm]';
            app.PixelsizeZmEditField = uieditfield(app.GeneralsettingsTab, 'numeric');
            app.PixelsizeZmEditField.UpperLimitInclusive = 'off';
            app.PixelsizeZmEditField.Limits = [0 Inf];
            app.PixelsizeZmEditField.Tooltip = {'Z-dimension of a single voxel. Used to calculate sizes, choose units carefully. By default in micrometer.'};
            app.PixelsizeZmEditField.Value = 0.05;
            app.ParticlediametermLabel = uilabel(app.GeneralsettingsTab);
            app.ParticlediametermLabel.Text = 'Particle diameter [μm]';
            app.ParticlediametermEditField = uieditfield(app.GeneralsettingsTab, 'numeric');
            app.ParticlediametermEditField.UpperLimitInclusive = 'off';
            app.ParticlediametermEditField.Limits = [0 Inf];
            app.ParticlediametermEditField.Tooltip = {'The approximate particle diameter in micrometer.'};
            app.ParticlediametermEditField.Value = 0.5;
            app.ResetdefaultvaluesButton = uibutton(app.GeneralsettingsTab, 'push');
            app.ResetdefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdefaultvaluesButtonPushed, true);
            app.ResetdefaultvaluesButton.Text = 'Reset default values';
            app.CoordinatediagramTab = uitab(app.GeneralInputPanel);
            app.CoordinatediagramTab.Title = 'Coordinate diagram';
            app.InputboxsizeyLabel = uilabel(app.CoordinatediagramTab);
            app.InputboxsizeyLabel.Text = 'Input box size (y)';
            app.InputboxsizeyEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.InputboxsizeyEditField.Tooltip = {'The box edge in the y-dimension, or maximum y-value, of input data.'};
            app.InputboxsizeyEditField.Value = 31.4;
            app.InputboxsizexLabel = uilabel(app.CoordinatediagramTab);
            app.InputboxsizexLabel.Text = 'Input box size (x)';
            app.InputboxsizexEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.InputboxsizexEditField.Tooltip = {'The box edge in the x-dimension, or maximum x-value, of input data.'};
            app.InputboxsizexEditField.Value = 31.4;
            app.InputboxsizezLabel = uilabel(app.CoordinatediagramTab);
            app.InputboxsizezLabel.Text = 'Input box size (z)';
            app.InputboxsizezEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.InputboxsizezEditField.Tooltip = {'The box edge in the z-dimension, or maximum z-value, of input data.'};
            app.InputboxsizezEditField.Value = 31.4;
            app.PointspreadfunctionmxEditFieldLabel = uilabel(app.CoordinatediagramTab);
            app.PointspreadfunctionmxEditFieldLabel.Text = {'Point spread '; 'function [μm] (x)'};
            app.PointspreadfunctionmxEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.PointspreadfunctionmxEditField.Tooltip = {'Adds this much length to each particle in the x dimension, to mimic effects such as a point spread function from microscopy.'};
            app.PointspreadfunctionmxEditField.Value = 0.15;
            app.PointspreadfunctionmyEditFieldLabel = uilabel(app.CoordinatediagramTab);
            app.PointspreadfunctionmyEditFieldLabel.Text = {'Point spread '; 'function [μm] (y)'};
            app.PointspreadfunctionmyEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.PointspreadfunctionmyEditField.Tooltip = {'Adds this much length to each particle in the y dimension, to mimic effects such as a point spread function from microscopy.'};
            app.PointspreadfunctionmyEditField.Value = 0.15;
            app.PointspreadfunctionmzEditFieldLabel = uilabel(app.CoordinatediagramTab);
            app.PointspreadfunctionmzEditFieldLabel.Text = {'Point spread '; 'function [μm] (z)'};
            app.PointspreadfunctionmzEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.PointspreadfunctionmzEditField.Tooltip = {'Adds this much length to each particle in the z dimension, to mimic effects such as a point spread function from microscopy.'};
            app.PointspreadfunctionmzEditField.Value = 0.5;
            app.OutputboxsizezEditFieldLabel = uilabel(app.CoordinatediagramTab);
            app.OutputboxsizezEditFieldLabel.Text = 'Output box size [σ] (z)';
            app.OutputboxsizezEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.OutputboxsizezEditField.Tooltip = {'Sets the output box size in the x-dimension, in units of sigma.'};
            app.OutputboxsizezEditField.Value = 31.4;
            app.OutputboxsizeyEditFieldLabel = uilabel(app.CoordinatediagramTab);
            app.OutputboxsizeyEditFieldLabel.Text = 'Output box size [σ] (y)';
            app.OutputboxsizeyEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.OutputboxsizeyEditField.Tooltip = {'Sets the output box size in the x-dimension, in units of sigma.'};
            app.OutputboxsizeyEditField.Value = 31.4;
            app.OutputboxsizexEditFieldLabel = uilabel(app.CoordinatediagramTab);
            app.OutputboxsizexEditFieldLabel.Text = 'Output box size [σ] (x)';
            app.OutputboxsizexEditField = uieditfield(app.CoordinatediagramTab, 'numeric');
            app.OutputboxsizexEditField.Tooltip = {'Sets the output box size in the x-dimension, in units of sigma.'};
            app.OutputboxsizexEditField.Value = 31.4;
            % Show the figure after all components are created
            app.Gelscripts_GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gelscripts_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Gelscripts_GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Gelscripts_GUIUIFigure)
        end
    end
end